classdef PupilModel_FittingParam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        L_0dSliderLabel             matlab.ui.control.Label
        L_0dSlider                  matlab.ui.control.Slider
        l_0cSliderLabel             matlab.ui.control.Label
        l_0cSlider                  matlab.ui.control.Slider
        K_dSliderLabel              matlab.ui.control.Label
        K_dSlider                   matlab.ui.control.Slider
        K_cSliderLabel              matlab.ui.control.Label
        K_cSlider                   matlab.ui.control.Slider
        DSliderLabel                matlab.ui.control.Label
        DSlider                     matlab.ui.control.Slider
        P_0SliderLabel              matlab.ui.control.Label
        P_0Slider                   matlab.ui.control.Slider
        f_p_0SliderLabel            matlab.ui.control.Label
        f_p_0Slider                 matlab.ui.control.Slider
        f_s_0SliderLabel            matlab.ui.control.Label
        f_s_0Slider                 matlab.ui.control.Slider
        x_0SliderLabel              matlab.ui.control.Label
        x_0Slider                   matlab.ui.control.Slider
        x_1SliderLabel              matlab.ui.control.Label
        x_1Slider                   matlab.ui.control.Slider
        Delta_tpSliderLabel         matlab.ui.control.Label
        Delta_tpSlider              matlab.ui.control.Slider
        Delta_tsSliderLabel         matlab.ui.control.Label
        Delta_tsSlider              matlab.ui.control.Slider
        ConstantsfromFanYaoModel2011Label  matlab.ui.control.Label
        f_pSliderLabel              matlab.ui.control.Label
        f_pSlider                   matlab.ui.control.Slider
        f_sSliderLabel              matlab.ui.control.Label
        f_sSlider                   matlab.ui.control.Slider
        t_pSliderLabel              matlab.ui.control.Label
        t_pSlider                   matlab.ui.control.Slider
        t_sSliderLabel              matlab.ui.control.Label
        t_sSlider                   matlab.ui.control.Slider
        AdjustableValuesfromFanYaoModel2011Label  matlab.ui.control.Label
        ExperimentDropDownLabel     matlab.ui.control.Label
        ExperimentDropDown          matlab.ui.control.DropDown
        SpectrumDropDownLabel       matlab.ui.control.Label
        SpectrumDropDown            matlab.ui.control.DropDown
        LoadFitdataButton           matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        ControlLabel                matlab.ui.control.Label
        ParametermascfunctionLabel  matlab.ui.control.Label
        qSliderLabel                matlab.ui.control.Label
        qSlider                     matlab.ui.control.Slider
        rSliderLabel                matlab.ui.control.Label
        rSlider                     matlab.ui.control.Slider
        ExplanationGUILabel         matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        EditField                   matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_5                 matlab.ui.control.NumericEditField
        EditField_6                 matlab.ui.control.NumericEditField
        EditField_7                 matlab.ui.control.NumericEditField
        EditField_8                 matlab.ui.control.NumericEditField
        EditField_9                 matlab.ui.control.NumericEditField
        EditField_10                matlab.ui.control.NumericEditField
        EditField_11                matlab.ui.control.NumericEditField
        EditField_12                matlab.ui.control.NumericEditField
        EditField_13                matlab.ui.control.NumericEditField
        EditField_14                matlab.ui.control.NumericEditField
        EditField_15                matlab.ui.control.NumericEditField
        EditField_16                matlab.ui.control.NumericEditField
        EditField_17                matlab.ui.control.NumericEditField
        EditField_18                matlab.ui.control.NumericEditField
        UIAxesPhasic                matlab.ui.control.UIAxes
        UIAxesTonic                 matlab.ui.control.UIAxes
        UIAxesCombined              matlab.ui.control.UIAxes
        UIAxesMasc                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        L_0d;
        l_0c;
        K_d;
        K_c;
        D;
        f_p;
        f_s;
        P_0;
        tp;
        ts;
        f_p_i_0;
        f_s_i_0;
        x0_1;
        x0_2;
        Delta_tp;
        Delta_ts;
        q;r;
        ExperimentName;
        SpectrumName;
        plotUI_RawData_Tonic;
        plotUI_RawData_Phasic;
        plotUI_RawData_Combined;
        plotUI_DGL_Tonic;
        plotUI_DGL_Phasic;
        plotUI_Poly_Tonic;
        plotUI_Poly_Phasic;
        plotUI_MascFunction_A;
        plotUI_MascFunction_B;
        PlotUI_CombinedModel;
        xValue;
        xMascfunction;
        y_RawData;
        y_PolyData;
        y_DGLData;
        y_MascFunction_A;
        y_MascFunction_B;
        y_CombinedModel;
        Polynomfit;
        Initalpupildiameter;
        
    end
    
    methods (Access = private)
        
        function update_GUIValues(app)
            app.EditField.Value = app.L_0d;
            app.L_0dSlider.Value = app.L_0d;
            
            app.EditField_2.Value = app.l_0c;
            app.l_0cSlider.Value = app.l_0c;
            
            app.EditField_3.Value = app.K_d;
            app.K_dSlider.Value = app.K_d;
            
            app.EditField_4.Value = app.K_c;
            app.K_cSlider.Value = app.K_c;
            
            app.EditField_5.Value = app.f_p_i_0;
            app.f_p_0Slider.Value = app.f_p_i_0;
            
            app.EditField_6.Value = app.f_s_i_0;
            app.f_s_0Slider.Value = app.f_s_i_0;
            
            app.EditField_7.Value = app.x0_1;
            app.x_0Slider.Value = app.x0_1;
            
            app.EditField_8.Value = app.x0_2;
            app.x_1Slider.Value = app.x0_2;
            
            app.EditField_9.Value = app.f_p;
            app.f_pSlider.Value = app.f_p;
            
            app.EditField_10.Value = app.f_s;
            app.f_sSlider.Value = app.f_s;
            
            app.EditField_11.Value = app.tp;
            app.t_pSlider.Value = app.tp;
            
            app.EditField_12.Value = app.ts;
            app.t_sSlider.Value = app.ts;
            
            app.EditField_13.Value = app.D;
            app.DSlider.Value = app.D;
            
            app.EditField_14.Value = app.P_0;
            app.P_0Slider.Value = app.P_0;
            
            app.EditField_15.Value = app.Delta_tp;
            app.Delta_tpSlider.Value = app.Delta_tp;
            
            app.EditField_16.Value = app.Delta_ts;
            app.Delta_tsSlider.Value = app.Delta_ts;
            
            app.EditField_17.Value = app.q;
            app.qSlider.Value = app.q;
            
            app.EditField_18.Value = app.r;
            app.rSlider.Value = app.r;
            
        end
        
        function disable_UIElements(app)
            
            app.EditField.Enable = false;
            app.L_0dSlider.Enable = false;
            
            app.EditField_2.Enable = false;
            app.l_0cSlider.Enable = false;
            
            app.EditField_3.Enable = false;
            app.K_dSlider.Enable = false;
            
            app.EditField_4.Enable = false;
            app.K_cSlider.Enable = false;
            
            app.EditField_5.Enable = false;
            app.f_p_0Slider.Enable = false;
            
            app.EditField_6.Enable = false;
            app.f_s_0Slider.Enable = false;
            
            app.EditField_7.Enable = false;
            app.x_0Slider.Enable = false;
            
            app.EditField_8.Enable = false;
            app.x_1Slider.Enable = false;
            
            app.EditField_9.Enable = false;
            app.f_pSlider.Enable = false;
            
            app.EditField_10.Enable = false;
            app.f_sSlider.Enable = false;
            
            app.EditField_11.Enable = false;
            app.t_pSlider.Enable = false;
            
            app.EditField_12.Enable = false;
            app.t_sSlider.Enable = false;
            
            app.EditField_13.Enable = false;
            app.DSlider.Enable = false;
            
            app.EditField_14.Enable = false;
            app.P_0Slider.Enable = false;
            
            app.EditField_15.Enable = false;
            app.Delta_tpSlider.Enable = false;
            
            app.EditField_16.Enable = false;
            app.Delta_tsSlider.Enable = false;
            
            app.EditField_17.Enable = false;
            app.qSlider.Enable = false;
            
            app.EditField_18.Enable = false;
            app.rSlider.Enable = false;
        end
        
        function enable_UIElements(app)
            app.EditField.Enable = true;
            app.L_0dSlider.Enable = true;
            
            app.EditField_2.Enable = true;
            app.l_0cSlider.Enable = true;
            
            app.EditField_3.Enable = true;
            app.K_dSlider.Enable = true;
            
            app.EditField_4.Enable = true;
            app.K_cSlider.Enable = true;
            
            app.EditField_5.Enable = true;
            app.f_p_0Slider.Enable = true;
            
            app.EditField_6.Enable = true;
            app.f_s_0Slider.Enable = true;
            
            app.EditField_7.Enable = true;
            app.x_0Slider.Enable = true;
            
            app.EditField_8.Enable = true;
            app.x_1Slider.Enable = true;
            
            app.EditField_9.Enable = true;
            app.f_pSlider.Enable = true;
            
            app.EditField_10.Enable = true;
            app.f_sSlider.Enable = true;
            
            app.EditField_11.Enable = true;
            app.t_pSlider.Enable = true;
            
            app.EditField_12.Enable = true;
            app.t_sSlider.Enable = true;
            
            app.EditField_13.Enable = true;
            app.DSlider.Enable = true;
            
            app.EditField_14.Enable = true;
            app.P_0Slider.Enable = true;
            
            app.EditField_15.Enable = true;
            app.Delta_tpSlider.Enable = true;
            
            app.EditField_16.Enable = true;
            app.Delta_tsSlider.Enable = true;
            
            app.EditField_17.Enable = true;
            app.qSlider.Enable = true;
            
            app.EditField_18.Enable = true;
            app.rSlider.Enable = true;
            
        end
        
        function update_Plots(app)
            set(app.plotUI_DGL_Tonic, {'YData'},  num2cell(app.y_DGLData, 1))
            set(app.plotUI_DGL_Phasic, {'YData'},  num2cell(app.y_DGLData, 1))
            
            set(app.plotUI_RawData_Tonic, {'YData'},  num2cell(app.y_RawData, 1))
            set(app.plotUI_RawData_Phasic, {'YData'},  num2cell(app.y_RawData, 1))
            set(app.plotUI_RawData_Combined, {'YData'},  num2cell(app.y_RawData, 1))
            
            set(app.plotUI_Poly_Phasic, {'YData'},  num2cell(app.y_PolyData, 1))
            set(app.plotUI_Poly_Tonic, {'YData'},  num2cell(app.y_PolyData, 1))
            
            set(app.plotUI_MascFunction_A, {'YData'},  num2cell(app.y_MascFunction_A, 1))
            set(app.plotUI_MascFunction_B, {'YData'},  num2cell(app.y_MascFunction_B, 1))
            
            set(app.PlotUI_CombinedModel, {'YData'},  num2cell(app.y_CombinedModel, 1))
        end
        
        function setDGL_Values(app)
            if isequal(app.ExperimentName, 'Single')
                switch app.SpectrumName
                    case '450nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 20;
                        app.f_s = -1.1900;
                        app.P_0 = -0.8240;
                        app.tp = 0.4878;
                        app.ts = 1.2943;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '530nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = -0.6492;
                        app.f_s = 1.5375;
                        app.P_0 = -0.8800;
                        app.tp = 0.1000;
                        app.ts = 0.7335;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '610nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 13.9306;
                        app.f_s = 2.2250;
                        app.P_0 = -0.8240;
                        app.tp = 0.4722;
                        app.ts = 1.4561;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '660nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 15.3903;
                        app.f_s = 6.1930;
                        app.P_0 = -0.8240;
                        app.tp = 0.4377;
                        app.ts = 1.8500;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '2000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 5.3645;
                        app.f_s = 0.6127;
                        app.P_0 = -0.8240;
                        app.tp = 0.4018;
                        app.ts = 1.3097;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '5000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = -0.6492;
                        app.f_s = 0.6976;
                        app.P_0 = -0.8800;
                        app.tp = 0.1000;
                        app.ts = 0.4306;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                    case '10000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 0.5889;
                        app.f_s = 1.1245;
                        app.P_0 = -0.8800;
                        app.tp = 0.1000;
                        app.ts = 0.9682;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(33, 199.45, 53.1, 2, true, 'Single');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.5524;
                        app.r = 0.0248;
                end
            elseif isequal(app.ExperimentName, 'Many')
                switch app.SpectrumName
                    case '450nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 19.2070;
                        app.f_s = -1.1900;
                        app.P_0 = -0.8240;
                        app.tp = 0.4878;
                        app.ts = 1.1483;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.5154;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '530nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 10.1836;
                        app.f_s = -0.5688;
                        app.P_0 = -0.8800;
                        app.tp = 0.2903;
                        app.ts = 0.7335;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '610nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 13.5488;
                        app.f_s = 2.2250;
                        app.P_0 = -0.8240;
                        app.tp = 0.3933;
                        app.ts = 1.9925;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '660nm'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 15.3903;
                        app.f_s = 6.1930;
                        app.P_0 = -0.8240;
                        app.tp = 0.4898;
                        app.ts = 1.8500;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '2000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 9.6961;
                        app.f_s = 0.6127;
                        app.P_0 = -0.8240;
                        app.tp = 0.4778;
                        app.ts = 1.3097;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.1342;
                        app.Delta_ts = 0.0878;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '5000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = -0.6492;
                        app.f_s = 0.6976;
                        app.P_0 = -0.8800;
                        app.tp = 0.1000;
                        app.ts = 0.8967;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                    case '10000K'
                        app.L_0d = 3.3403;
                        app.l_0c = 1.0710;
                        app.K_d = 1.0714;
                        app.K_c = 0;
                        app.D = 3.4855;
                        app.f_p = 0.9060;
                        app.f_s =  0.5659;
                        app.P_0 = -0.8800;
                        app.tp = 0.5197;
                        app.ts = 1.0311;
                        app.f_p_i_0 = 0;
                        app.f_s_i_0 = 0;
                        app.x0_1 = getPupilSizeWatson(22.1, 199.45, 53.1, 2, true, 'Many');
                        app.x0_2 = 0;
                        app.Delta_tp = 0.0604;
                        app.Delta_ts = 0.8696;
                        
                        app.q = 1.1359; % 1.5524;
                        app.r = 0.3517; % 0.0248;
                end
            end
        end
        
        function update_PlotValues(app)
            % Update DGL Data
            app.y_DGLData = Plot_DGL_Equation_1(app.L_0d, app.l_0c,...
                app.K_d, app.K_c, app.D, app.f_p, app.f_s, app.P_0, app.tp, app.ts,...
                app.f_p_i_0, app.f_s_i_0, app.x0_1, app.x0_2, app.Delta_tp, app.Delta_ts, app.xValue);
            
            % Update Polynom data
            app.y_PolyData = app.Polynomfit(app.xValue);
            
            % Update masc data
            y_mascfunction = Masc_function(app.xMascfunction, app.q, app.r);
            app.y_MascFunction_A = y_mascfunction(:,1);
            app.y_MascFunction_B = y_mascfunction(:,2);
            
            
            % Update combined model data
            try
                y_mascfunctionCombinedModel = Masc_function(app.xValue, app.q, app.r);
                app.y_CombinedModel = app.y_DGLData.* y_mascfunctionCombinedModel(:,1) + app.y_PolyData .* y_mascfunctionCombinedModel(:,2);
            catch ME
                errorMessage = sprintf('Error in function F_1.\n\nError Message:\n%s', ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Path
            addpath("GUI/");
            
            % Plot style
            set(groot, 'DefaultLineLineWidth', 2);
            set(groot, 'DefaultAxesLineWidth', 1);
            set(groot, 'DefaultAxesFontName', 'CMU Serif');
            set(groot, 'DefaultAxesFontSize', 12);
            set(groot, 'DefaultAxesFontWeight', 'normal');
            set(groot, 'DefaultAxesXMinorTick', 'on');
            set(groot, 'DefaultAxesGridLineStyle', ':');
            
            % Create basic values
            app.xValue = linspace(0, 300, 30001)';
            app.xMascfunction = linspace(-10, 10, 100)';
            app.ExperimentName = 'Single';
            app.SpectrumName = '450nm';
            
            % create basic constants for DGL
            
            % Set slider and number values in GUI
            app.disable_UIElements();
            
            % Plot empty raw data to UIaxes -----------------
            
            % Plot raw data objects to UIAxes
            hold(app.UIAxesPhasic, 'on'); hold(app.UIAxesTonic, 'on'); hold(app.UIAxesCombined, 'on');
            app.plotUI_RawData_Phasic = plot(app.UIAxesPhasic, app.xValue, repmat(2,30001,1), 'k',...
                'DisplayName','Raw Data');
            app.plotUI_RawData_Tonic = plot(app.UIAxesTonic, app.xValue, repmat(2,30001,1), 'k',...
                'DisplayName','Raw Data');
            app.plotUI_RawData_Combined = plot(app.UIAxesCombined, app.xValue, repmat(2,30001,1), 'k',...
                'DisplayName','Raw Data');
            legend(app.UIAxesPhasic, 'NumColumns',3, 'Location', 'north')
            hold(app.UIAxesPhasic, 'off'); hold(app.UIAxesTonic, 'off'); hold(app.UIAxesCombined, 'off');
            
            % Plot empty DGL data to UIaxes
            hold(app.UIAxesPhasic, 'on'); hold(app.UIAxesTonic, 'on');
            app.plotUI_DGL_Phasic = plot(app.UIAxesPhasic, app.xValue,  repmat(2,30001,1), 'r',...
                'DisplayName','Phasic model');
            app.plotUI_DGL_Tonic = plot(app.UIAxesTonic, app.xValue,  repmat(2,30001,1), 'r',...
                'DisplayName','Phasic model');
            hold(app.UIAxesPhasic, 'off'); hold(app.UIAxesTonic, 'off');
            
            % Plot empty Polynom data to UIaxes
            hold(app.UIAxesPhasic, 'on'); hold(app.UIAxesTonic, 'on');
            app.plotUI_Poly_Phasic = plot(app.UIAxesPhasic, app.xValue,  repmat(2,30001,1), 'g',...
                'DisplayName','Tonic Model');
            app.plotUI_Poly_Tonic = plot(app.UIAxesTonic,app.xValue,  repmat(2,30001,1), 'g',...
                'DisplayName','Tonic Model');
            hold(app.UIAxesPhasic, 'off');
            hold(app.UIAxesTonic, 'off');
            
            % Plot empty masc function to UIaxes
            hold(app.UIAxesMasc, 'on');
            app.plotUI_MascFunction_A = plot(app.UIAxesMasc, app.xMascfunction,  repmat(1,100,1), 'b');
            app.plotUI_MascFunction_B = plot(app.UIAxesMasc,app.xMascfunction,  repmat(1,100,1), 'r');
            hold(app.UIAxesMasc, 'off');
            
            % Plot empty combined model UIaxes
            hold(app.UIAxesCombined, 'on');
            app.PlotUI_CombinedModel = plot(app.UIAxesCombined, app.xValue,  repmat(2,30001,1), 'b');
            hold(app.UIAxesCombined, 'off');
            
            % Turn on legends
            legend(app.UIAxesPhasic, 'NumColumns',3, 'Location', 'north')
            legend(app.UIAxesTonic, 'NumColumns',3, 'Location', 'north')
            legend(app.UIAxesCombined, 'NumColumns',3, 'Location', 'north')
            
            % Axis Limits
            app.UIAxesTonic.XLim = [0 300];
            app.UIAxesTonic.YLim = [1.8 4.5];
            app.UIAxesPhasic.XLim = [0 2.5];
            app.UIAxesPhasic.YLim = [1.8 4.5];
            app.UIAxesCombined.XLim = [0 300];
            app.UIAxesCombined.YLim = [1.8 4.5];
            
            
        end

        % Callback function
        function SliderValue_Changing(app, event)
            %changingValue = event.Value;
            app.K_d = app.K_dSlider.Value;
            app.K_c = app.K_cSlider.Value;
            app.f_p_i_0 = app.f_p_0Slider.Value;
            app.f_s_i_0 = app.f_s_0Slider.Value;
            app.x0_1 = app.x_0Slider.Value;
            app.x0_2 = app.x_1Slider.Value;
            app.f_p = app.f_pSlider.Value;
            app.f_s = app.f_sSlider.Value;
            app.tp = app.t_pSlider.Value;
            app.ts = app.t_sSlider.Value;
            app.D = app.DSlider.Value;
            app.P_0 = app.P_0Slider.Value;
            app.Delta_tp = app.Delta_tpSlider.Value;
            app.Delta_ts = app.Delta_tsSlider.Value;
            app.q = app.qSlider.Value;
            app.r = app.rSlider.Value;
            app.ExperimentName = app.ExperimentDropDown.Value;
            app.SpectrumName = app.SpectrumDropDown.Value;
         
        end

        % Value changing function: L_0dSlider
        function L_0dSliderValueChanging(app, event)
            changingValue = event.Value;
            app.L_0d = changingValue;
            app.EditField.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: l_0cSlider
        function l_0cSliderValueChanging(app, event)
            changingValue = event.Value;
            app.l_0c = changingValue;
            app.EditField_2.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: K_dSlider
        function K_dSliderValueChanging(app, event)
            changingValue = event.Value;
            app.K_d = changingValue;
            app.EditField_3.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: K_cSlider
        function K_cSliderValueChanging(app, event)
            changingValue = event.Value;
            app.K_c = changingValue;
            app.EditField_4.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: f_p_0Slider
        function f_p_0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.f_p_i_0 = changingValue;
            app.EditField_5.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: f_s_0Slider
        function f_s_0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.f_s_i_0 = changingValue;
            app.EditField_6.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: x_0Slider
        function x_0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.x0_1 = changingValue;
            app.EditField_7.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: x_1Slider
        function x_1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.x0_2 = changingValue;
            app.EditField_8.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: f_pSlider
        function f_pSliderValueChanging(app, event)
            changingValue = event.Value;
            app.f_p = changingValue;
            app.EditField_9.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: f_sSlider
        function f_sSliderValueChanging(app, event)
            changingValue = event.Value;
            app.f_s = changingValue;
            app.EditField_10.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: t_pSlider
        function t_pSliderValueChanging(app, event)
            changingValue = event.Value;
            app.tp = changingValue;
            app.EditField_11.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: t_sSlider
        function t_sSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ts = changingValue;
            app.EditField_12.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: DSlider
        function DSliderValueChanging(app, event)
            changingValue = event.Value;
            app.D = changingValue;
            app.EditField_13.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: P_0Slider
        function P_0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.P_0 = changingValue;
            app.EditField_14.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: Delta_tpSlider
        function Delta_tpSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Delta_tp = changingValue;
            app.EditField_15.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: Delta_tsSlider
        function Delta_tsSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Delta_ts = changingValue;
            app.EditField_16.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: qSlider
        function qSliderValueChanging(app, event)
            changingValue = event.Value;
            app.q = changingValue;
            app.EditField_17.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Value changing function: rSlider
        function rSliderValueChanging(app, event)
            changingValue = event.Value;
            app.r = changingValue;
            app.EditField_18.Value = changingValue;
            app.update_PlotValues();
            app.update_Plots();
        end

        % Button pushed function: LoadFitdataButton
        function LoadFitdataButtonPushed(app, event)
            load('GUI_Data.mat')
            if isequal(app.ExperimentName, 'Single')
                switch app.SpectrumName
                    case '450nm'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.Spektrum450nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_450nm;
                    case '530nm'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.Spektrum530nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_530nm;
                    case '610nm'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.Spektrum610nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_610nm;
                    case '660nm'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.Spektrum660nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_660nm;
                    case '2000K'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.CCT2000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_2000K;
                    case '5000K'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.CCT5000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_5000K;
                    case '10000K'
                        app.y_RawData = Pupillendaten_Wide_OneSubject_Calc.CCT10000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Single.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Single.fitresult_10000K;
                end
            elseif isequal(app.ExperimentName, 'Many')
                switch app.SpectrumName
                    case '450nm'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.Spektrum450nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_450nm;
                    case '530nm'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.Spektrum530nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_530nm;
                    case '610nm'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.Spektrum610nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_610nm;
                    case '660nm'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.Spektrum660nm.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_660nm;
                    case '2000K'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.CCT2000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_2000K;
                    case '5000K'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.CCT5000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_5000K;
                    case '10000K'
                        app.y_RawData = Pupillendaten_Wide_ManySubject_Calc.CCT10000K.Median;
                        app.Initalpupildiameter = InitialPupilDiameter_Many.PredictedOffsetCorrected;
                        app.Polynomfit = fitresult_Many.fitresult_10000K;
                end
            end
            app.enable_UIElements();
            app.setDGL_Values();
            app.update_GUIValues();
            app.update_PlotValues
            app.update_Plots();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.LoadFitdataButtonPushed();
        end

        % Value changed function: ExperimentDropDown
        function ExperimentDropDownValueChanged(app, event)
            value = app.ExperimentDropDown.Value;
            switch value
                case 'Single Subject'
                    app.ExperimentName = 'Single';
                case 'Multi Subject'
                    app.ExperimentName = 'Many';
            end
        end

        % Value changed function: SpectrumDropDown
        function SpectrumDropDownValueChanged(app, event)
            value = app.SpectrumDropDown.Value;
            switch value
                case 'LED: 450 nm'
                    app.SpectrumName = '450nm';
                case 'LED: 530 nm'
                    app.SpectrumName = '530nm';
                case 'LED: 610 nm'
                    app.SpectrumName = '610nm';
                case 'LED: 660 nm'
                    app.SpectrumName = '660nm';
                case 'Poly: 2000 K'
                    app.SpectrumName = '2000K';
                case 'Poly: 5000 K'
                    app.SpectrumName = '5000K';
                case 'Poly: 10 000 K'
                    app.SpectrumName = '10000K';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.L_0dSliderLabel = uilabel(app.UIFigure);
            app.L_0dSliderLabel.Text = 'L_0d';
            app.L_0dSlider = uislider(app.UIFigure);
            app.L_0dSlider.Limits = [1 10];
            app.L_0dSlider.ValueChangingFcn = createCallbackFcn(app, @L_0dSliderValueChanging, true);
            app.L_0dSlider.Value = 1;
            app.l_0cSliderLabel = uilabel(app.UIFigure);
            app.l_0cSliderLabel.Text = 'l_0c';
            app.l_0cSlider = uislider(app.UIFigure);
            app.l_0cSlider.Limits = [0 5];
            app.l_0cSlider.ValueChangingFcn = createCallbackFcn(app, @l_0cSliderValueChanging, true);
            app.K_dSliderLabel = uilabel(app.UIFigure);
            app.K_dSliderLabel.Text = 'K_d';
            app.K_dSlider = uislider(app.UIFigure);
            app.K_dSlider.Limits = [0 5];
            app.K_dSlider.ValueChangingFcn = createCallbackFcn(app, @K_dSliderValueChanging, true);
            app.K_cSliderLabel = uilabel(app.UIFigure);
            app.K_cSliderLabel.Text = 'K_c';
            app.K_cSlider = uislider(app.UIFigure);
            app.K_cSlider.Limits = [0 40];
            app.K_cSlider.ValueChangingFcn = createCallbackFcn(app, @K_cSliderValueChanging, true);
            app.DSliderLabel = uilabel(app.UIFigure);
            app.DSliderLabel.Text = 'D';
            app.DSlider = uislider(app.UIFigure);
            app.DSlider.Limits = [0 5];
            app.DSlider.ValueChangingFcn = createCallbackFcn(app, @DSliderValueChanging, true);
            app.P_0SliderLabel = uilabel(app.UIFigure);
            app.P_0SliderLabel.Text = 'P_0';
            app.P_0Slider = uislider(app.UIFigure);
            app.P_0Slider.Limits = [-1.5 0];
            app.P_0Slider.ValueChangingFcn = createCallbackFcn(app, @P_0SliderValueChanging, true);
            app.f_p_0SliderLabel = uilabel(app.UIFigure);
            app.f_p_0SliderLabel.Text = 'f_p_0';
            app.f_p_0Slider = uislider(app.UIFigure);
            app.f_p_0Slider.Limits = [0 2];
            app.f_p_0Slider.ValueChangingFcn = createCallbackFcn(app, @f_p_0SliderValueChanging, true);
            app.f_s_0SliderLabel = uilabel(app.UIFigure);
            app.f_s_0SliderLabel.Text = 'f_s_0';
            app.f_s_0Slider = uislider(app.UIFigure);
            app.f_s_0Slider.Limits = [0 2];
            app.f_s_0Slider.ValueChangingFcn = createCallbackFcn(app, @f_s_0SliderValueChanging, true);
            app.x_0SliderLabel = uilabel(app.UIFigure);
            app.x_0SliderLabel.Text = 'x_0';
            app.x_0Slider = uislider(app.UIFigure);
            app.x_0Slider.Limits = [1.6 8];
            app.x_0Slider.ValueChangingFcn = createCallbackFcn(app, @x_0SliderValueChanging, true);
            app.x_0Slider.Value = 1.6;
            app.x_1SliderLabel = uilabel(app.UIFigure);
            app.x_1SliderLabel.Text = 'x_1';
            app.x_1Slider = uislider(app.UIFigure);
            app.x_1Slider.Limits = [0 1];
            app.x_1Slider.ValueChangingFcn = createCallbackFcn(app, @x_1SliderValueChanging, true);
            app.Delta_tpSliderLabel = uilabel(app.UIFigure);
            app.Delta_tpSliderLabel.Text = 'Delta_tp';
            app.Delta_tpSlider = uislider(app.UIFigure);
            app.Delta_tpSlider.Limits = [0 2];
            app.Delta_tpSlider.ValueChangingFcn = createCallbackFcn(app, @Delta_tpSliderValueChanging, true);
            app.Delta_tpSlider.Value = 0.1;
            app.Delta_tsSliderLabel = uilabel(app.UIFigure);
            app.Delta_tsSliderLabel.Text = 'Delta_ts';
            app.Delta_tsSlider = uislider(app.UIFigure);
            app.Delta_tsSlider.Limits = [0 2];
            app.Delta_tsSlider.ValueChangingFcn = createCallbackFcn(app, @Delta_tsSliderValueChanging, true);
            app.Delta_tsSlider.Value = 0.1;
            app.ConstantsfromFanYaoModel2011Label = uilabel(app.UIFigure);
            app.ConstantsfromFanYaoModel2011Label.Text = 'Constants from Fan & Yao Model 2011';
            app.f_pSliderLabel = uilabel(app.UIFigure);
            app.f_pSliderLabel.Text = 'f_p';
            app.f_pSlider = uislider(app.UIFigure);
            app.f_pSlider.Limits = [-5 25];
            app.f_pSlider.ValueChangingFcn = createCallbackFcn(app, @f_pSliderValueChanging, true);
            app.f_pSlider.Value = 5;
            app.f_sSliderLabel = uilabel(app.UIFigure);
            app.f_sSliderLabel.Text = 'f_s';
            app.f_sSlider = uislider(app.UIFigure);
            app.f_sSlider.Limits = [-3 10];
            app.f_sSlider.ValueChangingFcn = createCallbackFcn(app, @f_sSliderValueChanging, true);
            app.f_sSlider.Value = 5;
            app.t_pSliderLabel = uilabel(app.UIFigure);
            app.t_pSliderLabel.Text = 't_p';
            app.t_pSlider = uislider(app.UIFigure);
            app.t_pSlider.Limits = [0 1.5];
            app.t_pSlider.ValueChangingFcn = createCallbackFcn(app, @t_pSliderValueChanging, true);
            app.t_sSliderLabel = uilabel(app.UIFigure);
            app.t_sSliderLabel.Text = 't_s';
            app.t_sSlider = uislider(app.UIFigure);
            app.t_sSlider.Limits = [0.2 2];
            app.t_sSlider.ValueChangingFcn = createCallbackFcn(app, @t_sSliderValueChanging, true);
            app.t_sSlider.Value = 0.2;
            app.AdjustableValuesfromFanYaoModel2011Label = uilabel(app.UIFigure);
            app.AdjustableValuesfromFanYaoModel2011Label.Text = 'Adjustable Values from Fan & Yao Model 2011';
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDown.Items = {'Single Subject', 'Multi Subject'};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @ExperimentDropDownValueChanged, true);
            app.ExperimentDropDown.Value = 'Single Subject';
            app.SpectrumDropDownLabel = uilabel(app.UIFigure);
            app.SpectrumDropDownLabel.Text = 'Spectrum';
            app.SpectrumDropDown = uidropdown(app.UIFigure);
            app.SpectrumDropDown.Items = {'LED: 450 nm', 'LED: 530 nm', 'LED: 610 nm', 'LED: 660 nm', 'Poly: 2000 K', 'Poly: 5000 K', 'Poly: 10 000 K'};
            app.SpectrumDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrumDropDownValueChanged, true);
            app.SpectrumDropDown.Value = 'LED: 450 nm';
            app.LoadFitdataButton = uibutton(app.UIFigure, 'push');
            app.LoadFitdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFitdataButtonPushed, true);
            app.LoadFitdataButton.Text = 'Load & Fit data';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ControlLabel = uilabel(app.UIFigure);
            app.ControlLabel.Text = 'Control';
            app.ParametermascfunctionLabel = uilabel(app.UIFigure);
            app.ParametermascfunctionLabel.Text = 'Parameter masc function';
            app.qSliderLabel = uilabel(app.UIFigure);
            app.qSliderLabel.Text = 'q';
            app.qSlider = uislider(app.UIFigure);
            app.qSlider.Limits = [0.1 10];
            app.qSlider.ValueChangingFcn = createCallbackFcn(app, @qSliderValueChanging, true);
            app.qSlider.Value = 0.1;
            app.rSliderLabel = uilabel(app.UIFigure);
            app.rSliderLabel.Text = 'r';
            app.rSlider = uislider(app.UIFigure);
            app.rSlider.Limits = [0 2];
            app.rSlider.ValueChangingFcn = createCallbackFcn(app, @rSliderValueChanging, true);
            app.rSlider.Value = 0.1;
            app.ExplanationGUILabel = uilabel(app.UIFigure);
            app.ExplanationGUILabel.Text = 'Explanation GUI';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'The model consists of two components. The phasic pupil diameter is described by Fan & Yao 2011 and the tonic pupil diameter by a polynomial equation of 9th degree. Both functions are combined with a masking function. With this GUI the raw data (calculated median) from two pupil experiments with each seven used spectra can be loaded and the parameters of the masking function and the tonic model can be changed to see the effect on the whole model. '};
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_9 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_15 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_16 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_17 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_18 = uieditfield(app.UIFigure, 'numeric');
            app.UIAxesPhasic = uiaxes(app.UIFigure);
            title(app.UIAxesPhasic, 'Phasic pupil response')
            xlabel(app.UIAxesPhasic, 'Time in seconds')
            ylabel(app.UIAxesPhasic, 'Pupil diameter in mm')
            app.UIAxesTonic = uiaxes(app.UIFigure);
            title(app.UIAxesTonic, 'Tonic pupil response')
            xlabel(app.UIAxesTonic, 'Time in seconds')
            ylabel(app.UIAxesTonic, 'Pupil diameter in mm')
            app.UIAxesCombined = uiaxes(app.UIFigure);
            title(app.UIAxesCombined, 'Combined model')
            xlabel(app.UIAxesCombined, 'Time in seconds')
            ylabel(app.UIAxesCombined, 'Pupil diameter in mm')
            app.UIAxesMasc = uiaxes(app.UIFigure);
            title(app.UIAxesMasc, 'Masc function')
            xlabel(app.UIAxesMasc, 'x')
            ylabel(app.UIAxesMasc, 'y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PupilModel_FittingParam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end